classdef EnfCV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InstruccionesTextArea_3       matlab.ui.control.TextArea
        InstruccionesTextArea_3Label  matlab.ui.control.Label
        InstruccionesTextArea_2       matlab.ui.control.TextArea
        InstruccionesTextArea_2Label  matlab.ui.control.Label
        InstruccionesTextArea         matlab.ui.control.TextArea
        InstruccionesTextAreaLabel    matlab.ui.control.Label
        DesvColesterol                matlab.ui.control.NumericEditField
        IMCEditField_2Label           matlab.ui.control.Label
        DesvPresionarterialmedia      matlab.ui.control.NumericEditField
        MediaColesterol               matlab.ui.control.NumericEditField
        ColesterolLabel               matlab.ui.control.Label
        DesvIMC                       matlab.ui.control.NumericEditField
        DesviacinEditFieldLabel       matlab.ui.control.Label
        MediaPresionarterialmedia     matlab.ui.control.NumericEditField
        PresinarterialmediaLabel      matlab.ui.control.Label
        MediaIMC                      matlab.ui.control.NumericEditField
        MediaEditFieldLabel           matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        NiveldeglucosaVsPresinarterialmediaButton  matlab.ui.control.Button
        IMCvsColesterolButton         matlab.ui.control.Button
        GraficobarrasButton           matlab.ui.control.Button
        EnfermedadCVLabel             matlab.ui.control.Label
        CategoriaOMSLabel             matlab.ui.control.Label
        ActfsicaLabel                 matlab.ui.control.Label
        FumadorLabel                  matlab.ui.control.Label
        AlcoholLabel                  matlab.ui.control.Label
        IDdelpartEditField            matlab.ui.control.NumericEditField
        IDdelparticipanteEditFieldLabel  matlab.ui.control.Label
        ObtenerdatosButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       HeartData;
    end
    
    methods (Access = private)
        
        function preprocess(app)
    app.HeartData.age = (app.HeartData.age)/365.25;
    app.HeartData.height = app.HeartData.height / 100;
    app.HeartData.active = categorical(app.HeartData.active,[0 1], {'No realiza actividad física', 'Realiza actividad física'});
    app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No fuma', 'Fuma'});
    app.HeartData.alco = categorical(app.HeartData.alco, [0,1],{'Toma alcohol','No toma alcohol'});  
    app.HeartData.cardio=categorical(app.HeartData.cardio,[0 1],{'No Presenta enfermedades CV','Presenta enfermedades CV'});
  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("HeartData.mat","HeartData")
            app.HeartData = HeartData;
            preprocess(app);
        end

        % Button pushed function: ObtenerdatosButton
        function ObtenerdatosButtonPushed(app, event)
        id = app.IDdelpartEditField.Value;
        load('HeartData.mat');
        usuario= HeartData(HeartData.id == id, :);
        peso = app.HeartData.weight(id);
        altura = app.HeartData.height(id);
        IMC= peso/altura^2;

  if isempty(usuario)
                app.EnfermedadCVLabel.Text = 'No existe este ID.';
                app.FumadorLabel.Text = 'No existe este ID.';
                app.AlcoholLabel.Text = 'No existe este ID.';
                app.ActfsicaLabel.Text = 'No existe este ID.';
                app.CategoriaOMSLabel.Text = 'No existe este ID.';
                return;
            else 
   app.EnfermedadCVLabel.Text=app.HeartData.cardio(id);
   app.FumadorLabel.Text=app.HeartData.smoke(id);
   app.AlcoholLabel.Text=app.HeartData.alco(id);
   app.ActfsicaLabel.Text=app.HeartData.active(id);

           if (IMC < 18.5) 
            app.CategoriaOMSLabel.Text=("Bajo peso");
        elseif(IMC >= 18.5) && (IMC < 25)
            app.CategoriaOMSLabel.Text=("Peso normal");
        elseif(IMC >= 25) && (IMC < 30) 
            app.CategoriaOMSLabel.Text=("Sobrepeso");
        elseif(IMC >= 30) 
            app.CategoriaOMSLabel.Text=("Obesidad");
        end
  end


        end

        % Button pushed function: GraficobarrasButton
        function GraficobarrasButtonPushed(app, event)
            cla(app.UIAxes,"reset");
Hombres23 = 0;
Mujeres23 = 0;
Hombres34 = 0;
Mujeres34 = 0;
Hombres45 = 0;
Mujeres45 = 0;
Hombres56 = 0;
Mujeres56 = 0;
Hombres67 = 0;
Mujeres67 = 0;
Hombres78 = 0;
Mujeres78 = 0;


for i = 1:length(app.HeartData.age)
    if app.HeartData.cardio(i) == 'Presenta enfermedades CV'
        if app.HeartData.age(i) > 20 && app.HeartData.age(i)<=30
            if app.HeartData.gender(i) == 1
                Hombres23 = Hombres23 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres23 = Mujeres23 + 1;
            end

        elseif app.HeartData.age(i) > 30 && app.HeartData.age(i) <= 40
            if app.HeartData.gender(i) == 1
                Hombres34 = Hombres34 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres34 = Mujeres34 + 1;
            end
        
        elseif app.HeartData.age(i) > 40 && app.HeartData.age(i) <= 50
            if app.HeartData.gender(i) == 1
                Hombres45 = Hombres45 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres45 = Mujeres45 + 1;
            end
                
        elseif app.HeartData.age(i) > 50 && app.HeartData.age(i) <= 60
            if app.HeartData.gender(i) == 1
                Hombres56 = Hombres56 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres56 = Mujeres56 + 1;
            end
        
        elseif app.HeartData.age(i) > 60 && app.HeartData.age(i) <= 70
            if app.HeartData.gender(i) == 1
                Hombres67 = Hombres67 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres67 = Mujeres67 + 1;
            end
                    
        elseif app.HeartData.age(i) > 70 && app.HeartData.age(i) <=80

            if app.HeartData.gender(i) == 1
                Hombres78 = Hombres78 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres78 = Mujeres78 + 1;
            end
        end
    end
end
x = [ "20-30 años","30-40 años","40-50 años", "50-60 años", "60-70 años","70-80 años"];
y = [Hombres23 Mujeres23;Hombres34 Mujeres34;Hombres45 Mujeres45;Hombres56 Mujeres56; Hombres67 Mujeres67;Hombres78 Mujeres78];
    bar(app.UIAxes,x,y)
    set(get(app.UIAxes,'ylabel'),'string','N° personas con Enfermedades CV');
    set(get(app.UIAxes,'xlabel'),'string','Rango de edades');
    set(get(app.UIAxes,'title'),'string','Personas con Enfer CV según su edad');
    legend(app.UIAxes,'Hombres','Mujeres');
        end

        % Button pushed function: IMCvsColesterolButton
        function IMCvsColesterolButtonPushed(app, event)
        conCVIMC = zeros(1, length(app.HeartData.index));
        sinCVIMC = zeros(1, length(app.HeartData.index));
        conCVCol = zeros(1, length(app.HeartData.index));
        sinCVCol = zeros(1, length(app.HeartData.index));
        IMC2 = zeros(1, length(app.HeartData.index));
        
        
        for i = 1:length(app.HeartData.index)

            peso=app.HeartData.weight(i);
            altura=app.HeartData.height(i);
            IMC2(i) = peso/altura^2;

            if app.HeartData.cardio(i) == 'Presenta enfermedades CV'
                conCVIMC(i)=conCVIMC(i)+IMC2(i);
                conCVCol(i)=conCVCol(i)+ app.HeartData.cholesterol(i);
            elseif app.HeartData.cardio(i) == 'No presenta enfermedades CV'
                sinCVIMC(i)=sinCVIMC(i)+IMC2(i);
                sinCVCol(i)=sinCVCol(i)+app.HeartData.cholesterol(i);
            end
        end
        
   
        scatter(app.UIAxes,conCVCol, conCVIMC,"filled");
        hold(app.UIAxes,'on');
        scatter(app.UIAxes,sinCVCol, sinCVIMC,"filled");
        hold(app.UIAxes,'on');
        set(get(app.UIAxes,'xlabel'),'string','Colesterol');
        set(get(app.UIAxes,'ylabel'),'string','IMC');
        set(get(app.UIAxes,'title'),'string','IMC Vs. Colesterol');
        
        legend(app.UIAxes,'Con enfermedad CV','Sin enfermedad CV');
        end

        % Button pushed function: NiveldeglucosaVsPresinarterialmediaButton
        function NiveldeglucosaVsPresinarterialmediaButtonPushed(app, event)
        conCVGlu = zeros(1, length(app.HeartData.age));
        sinCVGlu = zeros(1, length(app.HeartData.age));
        conCVPres = zeros(1, length(app.HeartData.age));
        sinCVPres = zeros(1, length(app.HeartData.age));
        PresionMedia = zeros(1, length(app.HeartData.age));

        for i = 1:length(app.HeartData.age)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            PresionMedia(i)=(((presionsis)+(2*presiondias))/(3));

            if app.HeartData.cardio(i)== 'Presenta enfermedades CV'
                conCVGlu(i)=conCVGlu(i)+app.HeartData.gluc(i);
                conCVPres(i)=conCVPres(i)+PresionMedia(i);
            elseif app.HeartData.cardio(i)== 'No presenta enfermedades CV'
                sinCVGlu(i)=sinCVGlu(i)+app.HeartData.gluc(i);
                sinCVPres(i)=sinCVPres(i)+PresionMedia(i);
            end
        end
        

        scatter(app.UIAxes,conCVPres, conCVGlu, "filled");
        hold(app.UIAxes,'on');
        scatter(app.UIAxes,sinCVPres, sinCVGlu, "filled");
        hold(app.UIAxes,'on');
        set(get(app.UIAxes(1),'xlabel'),'string','Presión arterial media');
        set(get(app.UIAxes(1),'ylabel'),'string','Glucosa');
        set(get(app.UIAxes(1),'title'),'string','Glucosa Vs. Presión arterial media');
        
        legend(app.UIAxes,'Con enfermedad CV','Sin enfermedad CV');
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            IMC3= zeros(1, length(app.HeartData.weight));
             

        for i = 1:length(app.HeartData.age)

            peso=app.HeartData.weight(i);
      
            altura=app.HeartData.height(i);
            IMC3(i) = peso/altura^2;
            
        end
        app.DesvIMC.Value=(std(IMC3));
        app.MediaIMC.Value=(mean(IMC3));

        presionmedia = zeros(1, length(app.HeartData.ap_lo));
            
            presionsis=app.HeartData.ap_hi;
            presiondias=app.HeartData.ap_lo;
            presionmedia =(((presionsis)+(2*presiondias))/(3));


        for i = 1:length(presionmedia)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            presionmedia(i) =(((presionsis)+(2*presiondias))/(3));

        end
        app.DesvPresionarterialmedia.Value=(std(presionmedia));
        app.MediaPresionarterialmedia.Value=(mean(presionmedia));

        app.MediaColesterol.Value=(mean(app.HeartData.cholesterol));
               app.DesvColesterol.Value=(std(app.HeartData.cholesterol));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ObtenerdatosButton = uibutton(app.UIFigure, 'push');
            app.ObtenerdatosButton.ButtonPushedFcn = createCallbackFcn(app, @ObtenerdatosButtonPushed, true);
            app.ObtenerdatosButton.Text = 'Obtener datos';
            app.IDdelparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.IDdelparticipanteEditFieldLabel.Text = 'ID del participante';
            app.IDdelpartEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlcoholLabel = uilabel(app.UIFigure);
            app.AlcoholLabel.Text = '¿Toma alcohol?: ';
            app.FumadorLabel = uilabel(app.UIFigure);
            app.FumadorLabel.Text = '¿Es fumador?:';
            app.ActfsicaLabel = uilabel(app.UIFigure);
            app.ActfsicaLabel.Text = '¿Realiza actividad física?:';
            app.CategoriaOMSLabel = uilabel(app.UIFigure);
            app.CategoriaOMSLabel.Text = '¿En qué categoría se encuentra del IMC?:';
            app.EnfermedadCVLabel = uilabel(app.UIFigure);
            app.EnfermedadCVLabel.Text = '¿Presenta enfermedades cardiovaculares?:';
            app.GraficobarrasButton = uibutton(app.UIFigure, 'push');
            app.GraficobarrasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficobarrasButtonPushed, true);
            app.GraficobarrasButton.Text = 'Gráfico de barras';
            app.IMCvsColesterolButton = uibutton(app.UIFigure, 'push');
            app.IMCvsColesterolButton.ButtonPushedFcn = createCallbackFcn(app, @IMCvsColesterolButtonPushed, true);
            app.IMCvsColesterolButton.Text = 'IMC vs. Colesterol';
            app.NiveldeglucosaVsPresinarterialmediaButton = uibutton(app.UIFigure, 'push');
            app.NiveldeglucosaVsPresinarterialmediaButton.ButtonPushedFcn = createCallbackFcn(app, @NiveldeglucosaVsPresinarterialmediaButtonPushed, true);
            app.NiveldeglucosaVsPresinarterialmediaButton.Text = 'Nivel de glucosa Vs. Presión arterial media';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MediaEditFieldLabel = uilabel(app.UIFigure);
            app.MediaEditFieldLabel.Text = 'Media';
            app.MediaIMC = uieditfield(app.UIFigure, 'numeric');
            app.PresinarterialmediaLabel = uilabel(app.UIFigure);
            app.PresinarterialmediaLabel.Text = 'Presión arterial media';
            app.MediaPresionarterialmedia = uieditfield(app.UIFigure, 'numeric');
            app.DesviacinEditFieldLabel = uilabel(app.UIFigure);
            app.DesviacinEditFieldLabel.Text = 'Desviación ';
            app.DesvIMC = uieditfield(app.UIFigure, 'numeric');
            app.ColesterolLabel = uilabel(app.UIFigure);
            app.ColesterolLabel.Text = 'Colesterol';
            app.MediaColesterol = uieditfield(app.UIFigure, 'numeric');
            app.DesvPresionarterialmedia = uieditfield(app.UIFigure, 'numeric');
            app.IMCEditField_2Label = uilabel(app.UIFigure);
            app.IMCEditField_2Label.Text = 'IMC';
            app.DesvColesterol = uieditfield(app.UIFigure, 'numeric');
            app.InstruccionesTextAreaLabel = uilabel(app.UIFigure);
            app.InstruccionesTextAreaLabel.Text = 'Instrucciones';
            app.InstruccionesTextArea = uitextarea(app.UIFigure);
            app.InstruccionesTextArea.Value = {'Ingresa el ID del participante, después presiona el botón "Obtener datos" y se mostrara la información escrita.'};
            app.InstruccionesTextArea_2Label = uilabel(app.UIFigure);
            app.InstruccionesTextArea_2Label.Text = 'Instrucciones';
            app.InstruccionesTextArea_2 = uitextarea(app.UIFigure);
            app.InstruccionesTextArea_2.Value = {'Indica la gráfica de que punto deseas ver, después presiona el botón "Graficar".'};
            app.InstruccionesTextArea_3Label = uilabel(app.UIFigure);
            app.InstruccionesTextArea_3Label.Text = 'Instrucciones';
            app.InstruccionesTextArea_3 = uitextarea(app.UIFigure);
            app.InstruccionesTextArea_3.Value = {'Indica la gráfica de que punto deseas ver, después presiona el botón "Graficar".'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnfCV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end